classdef challengeGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FreeViewpointGUIUIFigure     matlab.ui.Figure
        PercentagevalueofviewpointEditFieldLabel  matlab.ui.control.Label
        EditFieldPercentage          matlab.ui.control.NumericEditField
        SelectImage1Button           matlab.ui.control.Button
        EditField1                   matlab.ui.control.EditField
        SelectImage2Button           matlab.ui.control.Button
        EditField2                   matlab.ui.control.EditField
        UIAxes1                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        GeneratefreeviewpointButton  matlab.ui.control.Button
        CameraCalibrationMatrixDropDownLabel  matlab.ui.control.Label
        CameraCalibrationMatrixDropDown  matlab.ui.control.DropDown
    end


    properties (Access = public)
        Image1 = 0 % Description
        Image2 = 0% Description
        p  = 0.5 % Description
        camera = 1% Description
    end

    properties (Access = private)
        file1 % Description
        path1 % Description
        file2 % Description
        path2 % Description
        im1loaded = 0% Description
        im2loaded = 0% Description
        
    end


    methods (Access = private)

        % Button pushed function: SelectImage1Button
        function getpic1(app, event)
            [app.file1, app.path1] = uigetfile('./img/*.JPG');
            if app.file1 ~= 0
                app.EditField1.Value = app.file1;
                app.Image1 = imread(char(string(app.path1) + string(app.file1)));
                app.im1loaded = 1;
                imshow(app.Image1, 'Parent', app.UIAxes1);
            end
        end

        % Button pushed function: SelectImage2Button
        function getpic2(app, event)
            [app.file2, app.path2] = uigetfile('./img/*.JPG');
            if app.file2 ~= 0
                app.EditField2.Value = app.file2;
                app.Image2 = imread(char(string(app.path2) + string(app.file2)));
                app.im2loaded = 1;
                imshow(app.Image2, 'Parent', app.UIAxes2);                
            end
        end

        % Button pushed function: GeneratefreeviewpointButton
        function generate(app, event)
            if app.im1loaded == 1
                if app.im2loaded == 1
                    app.p = app.EditFieldPercentage.Value/100;
                    uiresume(app.FreeViewpointGUIUIFigure);
                end
            end
        end

        % Value changed function: CameraCalibrationMatrixDropDown
        function camerachanged(app, event)
            value = app.CameraCalibrationMatrixDropDown.Value;
            if value == 'Camera 1'
                app.camera = 1;
            elseif value == 'Camera 2'
                app.camera = 2;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FreeViewpointGUIUIFigure = uifigure;
            app.FreeViewpointGUIUIFigure.Name = 'Free Viewpoint GUI';
            app.PercentagevalueofviewpointEditFieldLabel = uilabel(app.FreeViewpointGUIUIFigure);
            app.PercentagevalueofviewpointEditFieldLabel.Text = 'Percentage value of viewpoint';
            app.EditFieldPercentage = uieditfield(app.FreeViewpointGUIUIFigure, 'numeric');
            app.EditFieldPercentage.Limits = [0 100];
            app.EditFieldPercentage.Value = 50;
            app.SelectImage1Button = uibutton(app.FreeViewpointGUIUIFigure, 'push');
            app.SelectImage1Button.ButtonPushedFcn = createCallbackFcn(app, @getpic1, true);
            app.SelectImage1Button.Text = 'Select Image 1';
            app.EditField1 = uieditfield(app.FreeViewpointGUIUIFigure, 'text');
            app.SelectImage2Button = uibutton(app.FreeViewpointGUIUIFigure, 'push');
            app.SelectImage2Button.ButtonPushedFcn = createCallbackFcn(app, @getpic2, true);
            app.SelectImage2Button.Text = 'Select Image 2';
            app.EditField2 = uieditfield(app.FreeViewpointGUIUIFigure, 'text');
            app.UIAxes1 = uiaxes(app.FreeViewpointGUIUIFigure);
            title(app.UIAxes1, 'Image 1')
            app.UIAxes2 = uiaxes(app.FreeViewpointGUIUIFigure);
            title(app.UIAxes2, 'Image 2')
            app.GeneratefreeviewpointButton = uibutton(app.FreeViewpointGUIUIFigure, 'push');
            app.GeneratefreeviewpointButton.ButtonPushedFcn = createCallbackFcn(app, @generate, true);
            app.GeneratefreeviewpointButton.Text = 'Generate free viewpoint';
            app.CameraCalibrationMatrixDropDownLabel = uilabel(app.FreeViewpointGUIUIFigure);
            app.CameraCalibrationMatrixDropDownLabel.Text = 'Camera Calibration Matrix';
            app.CameraCalibrationMatrixDropDown = uidropdown(app.FreeViewpointGUIUIFigure);
            app.CameraCalibrationMatrixDropDown.Items = {'Camera 1', 'Camera 2'};
            app.CameraCalibrationMatrixDropDown.ValueChangedFcn = createCallbackFcn(app, @camerachanged, true);
            app.CameraCalibrationMatrixDropDown.Value = 'Camera 1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = challengeGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FreeViewpointGUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FreeViewpointGUIUIFigure)
        end
    end
end